classdef Exercicio1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        fval                     matlab.ui.control.NumericEditField
        x                        matlab.ui.control.NumericEditField
        TextArea_2               matlab.ui.control.TextArea
        TextArea                 matlab.ui.control.TextArea
        ExplicaoEx1TextArea      matlab.ui.control.TextArea
        ValorDropDown            matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        MENUPRINCIPALButton      matlab.ui.control.Button
        SenoButton               matlab.ui.control.Button
        ToolboxButton            matlab.ui.control.Button
        OPTIMIZAOSENOCOMGALabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ToolboxButton
        function ToolboxButtonPushed(app, event)
            tipo = app.ValorDropDown.Value;  % "Mínimo" ou "Máximo"
    
            if strcmp(tipo,'Mínimo')
                run('gasenoMin.m');   % executa o GA do mínimo
            else
                run('gasenoMax.m');   % executa o GA do máximo
            end
        
        end

        % Button pushed function: MENUPRINCIPALButton
        function MENUPRINCIPALButtonPushed(app, event)
            MenuAPP;  % volta ao menu principal
            delete(app);
        

        end

        % Value changed function: x
        function xValueChanged(app, event)
            tipo = app.ValorDropDown.Value;  % "Mínimo" ou "Máximo"
            if strcmp(tipo,'Mínimo')
                run('gasenoMin.m');   % executa o GA do mínimo
           [x fval] = ga(@senoMin,1,[],[],[],[],0,2*pi,[],options);
            else
                run('gasenoMax.m');   % executa o GA do máximo
           [x fval] = ga(@senoMax,1,[],[],[],[],0,2*pi,[],options); 
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OPTIMIZAOSENOCOMGALabel = uilabel(app.GridLayout);
            app.OPTIMIZAOSENOCOMGALabel.Text = 'OPTIMIZAÇÃO SENO COM GA';
            app.ToolboxButton = uibutton(app.GridLayout, 'push');
            app.ToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @ToolboxButtonPushed, true);
            app.ToolboxButton.Text = 'Toolbox';
            app.SenoButton = uibutton(app.GridLayout, 'push');
            app.SenoButton.Text = 'Seno';
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MENUPRINCIPALButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.ValorDropDown = uidropdown(app.GridLayout);
            app.ValorDropDown.Items = {'Mínimo', 'Máximo'};
            app.ValorDropDown.Value = 'Mínimo';
            app.ExplicaoEx1TextArea = uitextarea(app.GridLayout);
            app.ExplicaoEx1TextArea.Value = {'EX1:'; 'Exercício 1 da ficha, que é encontrar o mínimo/máximo da função seno usando algoritmos genéticos (GA)'; ''; '%Programa de optimizacao'; '%X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)'};
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Gráficos representados!'; 'Toolbox;'; 'Valor;'};
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.Value = {'Escolha de valores:'; 'Mínimo:'; 'Máximo:'};
            app.x = uieditfield(app.GridLayout, 'numeric');
            app.x.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.fval = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exercicio1App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end