classdef Exercicio2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TextArea_ex2             matlab.ui.control.TextArea
        TextArea_Titulo          matlab.ui.control.TextArea
        PmEditField              matlab.ui.control.NumericEditField
        PmEditFieldLabel         matlab.ui.control.Label
        PcEditField              matlab.ui.control.NumericEditField
        PcEditFieldLabel         matlab.ui.control.Label
        NgerEditField            matlab.ui.control.NumericEditField
        NgerEditFieldLabel       matlab.ui.control.Label
        NpopEditField            matlab.ui.control.NumericEditField
        NpopEditFieldLabel       matlab.ui.control.Label
        PMaxEditField            matlab.ui.control.NumericEditField
        PMaxEditFieldLabel       matlab.ui.control.Label
        SairButton               matlab.ui.control.Button
        ToolboxButton            matlab.ui.control.Button
        ValorDropDown            matlab.ui.control.DropDown
        MENUPRINCIPALButton      matlab.ui.control.Button
        TextArea_setor2          matlab.ui.control.TextArea
        TextArea_setor1          matlab.ui.control.TextArea
        TextArea_setorprincipal  matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MENUPRINCIPALButton
        function MENUPRINCIPALButtonPushed(app, event)
            MenuAPP; %retorna ao Menu Principal
            delete(app);% sai da aplicação
        end

        % Button pushed function: ToolboxButton
        function ToolboxButtonPushed(app, event)
             % Ler valores da App
            Pmin = 0;   % fixo no problema
            PD=0.9; %alerta de amior que 0.9, nao esquecer meter
            Pmax = app.PMaxEditField.Value;
            Npop = app.NpopEditField.Value;
            Nger = app.NgerEditField.Value;
            Pc   = app.PcEditField.Value;
            Pm   = app.PmEditField.Value;
        
            % ============================
            %  VALIDAR INPUTS
            % ============================
            if Pmax <= Pmin
                uialert(app.UIFigure, 'Pmax deve ser maior que Pmin!', 'Erro');
                return;
            end
        
            if Npop <= 0 || mod(Npop,1)~=0
                uialert(app.UIFigure, 'O tamanho da população deve ser um inteiro > 0.', 'Erro');
                return;
            end
        
            if Nger <= 0 || mod(Nger,1)~=0
                uialert(app.UIFigure, 'O número de gerações deve ser um inteiro > 0.', 'Erro');
                return;
            end
        
            if Pc < 0 || Pc > 1
                uialert(app.UIFigure, 'Pc deve estar entre 0 e 1.', 'Erro');
                return;
            end
        
            if Pm < 0 || Pm > 1
                uialert(app.UIFigure, 'Pm deve estar entre 0 e 1.', 'Erro');
                return;
            end
        

            tipo = app.ValorDropDown.Value;  % "Selec.Roleta" ou "Selec.Torneio
        
            % ============================
            % 2) CORRER (esconde gráficos)
            % ============================
            warning('on','all');    % esconde as mensagens e gráficos internos da toolbox
            set(0,'DefaultFigureVisible','on');
        
            if strcmp(tipo,'Selec.Roleta')
                [PG1_opt, PG2_opt, custo_min, PG1_tool, PG2_tool, custo_min_tool] = ex2cselecroleta(Pmax, Npop, Nger, Pc, Pm);
            else
                run('ex2cselectorneio.m');
            end
        
            set(0,'DefaultFigureVisible','on');  % voltar ao normal
            warning('on','all');
        
        
            % ============================
            % 3) MOSTRAR RESULTADOS
            % ============================
            msg = sprintf([ ...
                '===== GA (teu código) =====\n' ...
                'PG1 ótimo = %.4f p.u.\n' ...
                'PG2 ótimo = %.4f p.u.\n' ...
                'Custo mínimo = %.6f (AC/h)\n' ...
                '==============================\n\n' ...
                '===== GA Toolbox =====\n' ...
                'PG1 (tool) = %.4f p.u.\n' ...
                'PG2 (tool) = %.4f p.u.\n' ...
                'Custo tool = %.6f (AC/h)\n' ...
                '==============================\n\n' ...
                'Comparação:\n' ...
                'Teu GA:  PG1 = %.4f | PG2 = %.4f | Custo = %.5f\n' ...
                'Toolbox: PG1 = %.4f | PG2 = %.4f | Custo = %.5f\n'], ...
                PG1_opt, PG2_opt, custo_min, ...
                PG1_tool, PG2_tool, custo_min_tool, ...
                PG1_opt, PG2_opt, custo_min, ...
                PG1_tool, PG2_tool, custo_min_tool);
        
            uialert(app.UIFigure, msg, 'Resultados');
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TextArea_setorprincipal = uitextarea(app.GridLayout);
            app.TextArea_setor1 = uitextarea(app.GridLayout);
            app.TextArea_setor2 = uitextarea(app.GridLayout);
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MENUPRINCIPALButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.ValorDropDown = uidropdown(app.GridLayout);
            app.ValorDropDown.Items = {'Selec.Roleta', 'Selec.Torneio'};
            app.ValorDropDown.Value = 'Selec.Roleta';
            app.ToolboxButton = uibutton(app.GridLayout, 'push');
            app.ToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @ToolboxButtonPushed, true);
            app.ToolboxButton.Text = 'Toolbox';
            app.SairButton = uibutton(app.GridLayout, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.PMaxEditFieldLabel = uilabel(app.GridLayout);
            app.PMaxEditFieldLabel.Text = 'PMax';
            app.PMaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.NpopEditFieldLabel = uilabel(app.GridLayout);
            app.NpopEditFieldLabel.Text = 'Npop';
            app.NpopEditField = uieditfield(app.GridLayout, 'numeric');
            app.NgerEditFieldLabel = uilabel(app.GridLayout);
            app.NgerEditFieldLabel.Text = 'Nger';
            app.NgerEditField = uieditfield(app.GridLayout, 'numeric');
            app.PcEditFieldLabel = uilabel(app.GridLayout);
            app.PcEditFieldLabel.Text = 'Pc';
            app.PcEditField = uieditfield(app.GridLayout, 'numeric');
            app.PmEditFieldLabel = uilabel(app.GridLayout);
            app.PmEditFieldLabel.Text = 'Pm';
            app.PmEditField = uieditfield(app.GridLayout, 'numeric');
            app.TextArea_Titulo = uitextarea(app.GridLayout);
            app.TextArea_Titulo.Value = {'Optimização-Despacho  GA'};
            app.TextArea_ex2 = uitextarea(app.GridLayout);
            app.TextArea_ex2.Value = {'EX2: Admitindo que a função custo de produção de cada um dos geradores'; 'são dadas pelas Equações (1) e (2):'; 'C1(PG1) = 0,3· PG1 + 0,01· P^2G1;'; 'C2(PG2) = 0,2· PG2 + 0,3· P^2G2;'; ''; 'Recorrendo à Toolbox de Otimização do MATLAB, o despacho económico sem perdas'; 'da rede da Figura 4 e comparação os resultados com os obtidos recorrendo aos multiplicadores de'; 'Lagrange.'; 'function z=custo(x)'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exercicio2App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end