classdef MenuAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Ex2TextArea          matlab.ui.control.TextArea
        Ex1TextArea          matlab.ui.control.TextArea
        SairButton           matlab.ui.control.Button
        MENUPRINCIPALButton  matlab.ui.control.Button
        Exerccio2Button      matlab.ui.control.Button
        Exerccio1Button      matlab.ui.control.Button
        APPMatlabAlgoritmosGenticosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MenuApp(app)
            
        end

        % Button pushed function: Exerccio1Button
        function Exerccio1ButtonPushed(app, event)
            Exercicio1App;  % abre a app do exercício 1
            delete(app);    % fecha o menu principal
        end

        % Button pushed function: Exerccio2Button
        function Exerccio2ButtonPushed(app, event)
            Exercicio2App;%abre a app do exercício 2
            delete(app);% defcha o menu principal
        end

        % Callback function: MENUPRINCIPALButton, UIFigure
        function MenuButtonPushed(app, event)
            MenuAPP;%retorna sempre ao menu principal 
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app);    % fecha o menu principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.APPMatlabAlgoritmosGenticosLabel = uilabel(app.GridLayout);
            app.APPMatlabAlgoritmosGenticosLabel.Text = 'APP-Matlab-Algoritmos-Genéticos';
            app.Exerccio1Button = uibutton(app.GridLayout, 'push');
            app.Exerccio1Button.ButtonPushedFcn = createCallbackFcn(app, @Exerccio1ButtonPushed, true);
            app.Exerccio1Button.Text = 'Exercício 1';
            app.Exerccio2Button = uibutton(app.GridLayout, 'push');
            app.Exerccio2Button.ButtonPushedFcn = createCallbackFcn(app, @Exerccio2ButtonPushed, true);
            app.Exerccio2Button.Text = 'Exercício 2';
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.SairButton = uibutton(app.GridLayout, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.Ex1TextArea = uitextarea(app.GridLayout);
            app.Ex1TextArea.Value = {'EX1:'; ''; 'Determinação do mínimo e do máximo da função seno'; 'Recorrendo à Toolbox de Otimização do MATLAB'};
            app.Ex2TextArea = uitextarea(app.GridLayout);
            app.Ex2TextArea.Value = {'EX2:'; ''; 'Despacho económico sem perdas da rede multiplicadores de Lagrange. Recorrendo à Toolbox de Otimização do MATLAB'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MenuAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MenuApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end